classdef Proyecto_Final_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        CargarAudioMenu  matlab.ui.container.Menu
        PlayButton       matlab.ui.control.StateButton
        KnobLabel        matlab.ui.control.Label
        Knob             matlab.ui.control.DiscreteKnob
        Knob2Label       matlab.ui.control.Label
        Knob2            matlab.ui.control.Knob
        TextAreaLabel    matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: CargarAudioMenu
        function CargarAudioMenuSelected(app, event)
            % Display uigetfile dialog
            [f,p,~] = uigetfile( ...
            {'*.mp4;*.wav',...
                'Audio Files (*.mp4;*.wav)';
               '*.*',  'All Files (*.*)'}, ...
               'Select a File');
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               PlayButtonValueChanged(app, fname);
            end
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value == 1
                PO=audioplayer(f,44100);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.CargarAudioMenu = uimenu(app.Menu);
            app.CargarAudioMenu.MenuSelectedFcn = createCallbackFcn(app, @CargarAudioMenuSelected, true);
            app.CargarAudioMenu.Accelerator = 'n';
            app.CargarAudioMenu.Text = 'Cargar Audio';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'continuous');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Para cargar un archivo abrir Menu'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto_Final_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end